classdef Generator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        UITable              matlab.ui.control.Table
        TabGroup             matlab.ui.container.TabGroup
        GeneralTab           matlab.ui.container.Tab
        DifficultyLabel      matlab.ui.control.Label
        DifficultyDropDown   matlab.ui.control.DropDown
        PartyLevelLabel      matlab.ui.control.Label
        PartyLevelEditField  matlab.ui.control.NumericEditField
        PartySizeLabel       matlab.ui.control.Label
        PartySizeEditField   matlab.ui.control.NumericEditField
        typeSwitch           matlab.ui.control.Switch
        GoButton             matlab.ui.control.Button
        Lamp                 matlab.ui.control.Lamp
        AdvancedTab          matlab.ui.container.Tab
    end

    
    properties (Access = public)
        Chlng = 1; % difficulty setting
        roomType = 0; % special or normal room
        PrtyLvl = 5;
        PrtySize = 4;
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            %initial perameters
            app.Lamp.Color = [1 0 0];
            drawnow
            app.roomType = app.typeSwitch.Value;
            app.Chlng = app.DifficultyDropDown.Value;
            app.PrtyLvl = app.PartyLevelEditField.Value;
            app.PrtySize = app.PartySizeEditField.Value;
            
            run DnD.m
            app.UITable.Data=room;
            app.Lamp.Color = [0 1 0];
        end

        % Value changed function: DifficultyDropDown
        function DifficultyDropDownValueChanged(app, event)
            app.Chlng = app.DifficultyDropDown.Value;
        end

        % Value changed function: typeSwitch
        function typeSwitchValueChanged(app, event)
           app.roomType = app.typeSwitch.Value;
    
        end

        % Value changed function: PartyLevelEditField
        function PartyLevelEditFieldValueChanged(app, event)
            app.PrtyLvl = app.PartyLevelEditField.Value;
            
        end

        % Value changed function: PartySizeEditField
        function PartySizeEditFieldValueChanged(app, event)
            app.PrtySize = app.PartySizeEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GeneralTab = uitab(app.TabGroup);
            app.GeneralTab.Title = 'General';
            app.DifficultyLabel = uilabel(app.GeneralTab);
            app.DifficultyLabel.Text = 'Difficulty:';
            app.DifficultyDropDown = uidropdown(app.GeneralTab);
            app.DifficultyDropDown.Items = {'Easy', 'Medium', 'Hard', 'Brutal', 'Insane'};
            app.DifficultyDropDown.ItemsData = {'0', '1', '2', '3', '4'};
            app.DifficultyDropDown.ValueChangedFcn = createCallbackFcn(app, @DifficultyDropDownValueChanged, true);
            app.DifficultyDropDown.Value = '1';
            app.PartyLevelLabel = uilabel(app.GeneralTab);
            app.PartyLevelLabel.Text = {'Party Level: '; ''};
            app.PartyLevelEditField = uieditfield(app.GeneralTab, 'numeric');
            app.PartyLevelEditField.Limits = [1 20];
            app.PartyLevelEditField.RoundFractionalValues = 'on';
            app.PartyLevelEditField.ValueChangedFcn = createCallbackFcn(app, @PartyLevelEditFieldValueChanged, true);
            app.PartyLevelEditField.Value = 5;
            app.PartySizeLabel = uilabel(app.GeneralTab);
            app.PartySizeLabel.Text = {'Party Size: '; ''};
            app.PartySizeEditField = uieditfield(app.GeneralTab, 'numeric');
            app.PartySizeEditField.Limits = [1 20];
            app.PartySizeEditField.RoundFractionalValues = 'on';
            app.PartySizeEditField.ValueChangedFcn = createCallbackFcn(app, @PartySizeEditFieldValueChanged, true);
            app.PartySizeEditField.Value = 4;
            app.typeSwitch = uiswitch(app.GeneralTab, 'slider');
            app.typeSwitch.Items = {'Normal', 'Special'};
            app.typeSwitch.ItemsData = {'0', '1'};
            app.typeSwitch.ValueChangedFcn = createCallbackFcn(app, @typeSwitchValueChanged, true);
            app.typeSwitch.Value = '0';
            app.GoButton = uibutton(app.GeneralTab, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = {'Go!'; ''};
            app.Lamp = uilamp(app.GeneralTab);
            app.AdvancedTab = uitab(app.TabGroup);
            app.AdvancedTab.Title = 'Advanced';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Generator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end